classdef GroundStationApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Panel                          matlab.ui.container.Panel
        DecimationFactor               matlab.ui.control.DropDown
        DecimationFactorDropDownLabel  matlab.ui.control.Label
        Timer_DataUpdate               matlab.ui.control.NumericEditField
        TimerEditFieldLabel_3          matlab.ui.control.Label
        GroundSlider                   matlab.ui.control.RangeSlider
        StratSlider                    matlab.ui.control.RangeSlider
        SyncPath_Ground                matlab.ui.control.EditField
        SyncPathStratLabel_2           matlab.ui.control.Label
        Leakage_Ground                 matlab.ui.control.Slider
        OverlapLabel_4                 matlab.ui.control.Label
        TimeRes_Ground                 matlab.ui.control.Slider
        TimeResLabel_2                 matlab.ui.control.Label
        Overlap_Ground                 matlab.ui.control.Slider
        OverlapLabel_3                 matlab.ui.control.Label
        Timer_Ground                   matlab.ui.control.NumericEditField
        TimerEditFieldLabel_2          matlab.ui.control.Label
        Leakage_Strat                  matlab.ui.control.Slider
        OverlapLabel_2                 matlab.ui.control.Label
        SoundSwitchLabel               matlab.ui.control.Label
        TimeRes_Strat                  matlab.ui.control.Slider
        TimeResLabel                   matlab.ui.control.Label
        Overlap_Strat                  matlab.ui.control.Slider
        OverlapLabel                   matlab.ui.control.Label
        SoundSwitch                    matlab.ui.control.ToggleSwitch
        Timer_Strat                    matlab.ui.control.NumericEditField
        TimerEditFieldLabel            matlab.ui.control.Label
        SyncPath_Strat                 matlab.ui.control.EditField
        SyncPathStratLabel             matlab.ui.control.Label
        Button                         matlab.ui.control.Button
        UIAxes_Ground                  matlab.ui.control.UIAxes
        UIAxes_Strat                   matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        image_strat;
        image_ground;
        pool = gcp('nocreate');
        timer_strat          timer;
        timer_ground         timer;
        Future_strat        parallel.FevalFuture;
        Future_ground       parallel.FevalFuture;
    end
    
    methods (Access = private)
        
        function filepath = RecentFile(~, filepath)
            files = dir(filepath);
            files = files(~ [files.isdir]);
            if isempty(files) 
                filepath=[];
                return;
            end
            sort([files.datenum], 'descend');
            filepath = fullfile(files(1).folder, files(1).name);
        end
        
        function loop_strat(app)
            t=tic;
            if isempty(app.pool)
                app.pool = parpool; % Start the parallel pool
            end

            file = memmapfile( RecentFile(app, app.SyncPath_Strat.Value) ,'Format','int16');
            if numel(file.Data) == 0
                return;
            end

            app.Future_strat = parfeval(app.pool, @ProcessDownsample, 4, ...
                file, ...
                app.Overlap_Strat.Value, ...
                app.TimeRes_Strat.Value, ...
                app.Leakage_Strat.Value);
            
            afterEach(app.Future_strat,@(P,F,T,data) display_strat(app,P,F,T,data), 0 );

            app.Timer_Strat.Value=toc(t);
        end
        
        
        function loop_ground(app)
            t=tic;
            if isempty(app.pool)
                app.pool = parpool; % Start the parallel pool
            end
            file = memmapfile( RecentFile(app, app.SyncPath_Ground.Value) ,'Format','int16');
            if numel(file.Data) == 0
                return;
            end

            % ProcessRaw(file, ...
            %     app.Overlap_Ground.Value, ...
            %     app.TimeRes_Ground.Value, ...
            %     app.Leakage_Ground.Value, ...
            %     app.DecimationFactor.Value);

            app.Future_ground = parfeval(app.pool, @ProcessRaw, 3, ...
                file, ...
                app.Overlap_Ground.Value, ...
                app.TimeRes_Ground.Value, ...
                app.Leakage_Ground.Value, ...
                app.DecimationFactor.Value);

            afterEach(app.Future_ground,@(P,F,T) set(app.image_ground, ...
                'CData',P,'XData',F,'Ydata',T), 0 );
            app.Timer_Ground.Value=toc(t);
        end
        
        function display_strat(app,P,F,T,snd)
            set(app.image_strat, ...
                'CData',P, ...
                'XData',F, ...
                'Ydata',T);
            if app.SoundSwitch.Value == "On"
                snd =snd.*exp(2i * pi * 2e3 * (0:length(snd)-1)'/44.1) ;
                sound(real(snd),44.1e3);
            end
        end

        function display_ground(app,P,F,T)
            set(app.image_ground,'CData',P,'XData',F,'Ydata',T);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startup(app)

            app.image_strat=imagesc(app.UIAxes_Strat,0,0,0);
            colorbar(app.UIAxes_Strat); % Add a colorbar to show the magnitude scale
            colormap(app.UIAxes_Strat,"prism");
            axis(app.UIAxes_Strat,'tight');
            clim(app.UIAxes_Strat,app.StratSlider.Value);

            app.image_ground = imagesc(app.UIAxes_Ground,0,0,0);
            colorbar(app.UIAxes_Ground); % Add a colorbar to show the magnitude scale
            colormap(app.UIAxes_Ground,"parula");
            axis(app.UIAxes_Ground,'tight');
            clim(app.UIAxes_Ground,app.GroundSlider.Value);

        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            
            loop_strat(app);
            loop_ground(app);
            % [P,F,T] = fetchOutputs(app.Future_strat);
            % set(app.image_strat, ...
            %     'CData',P, ...
            %     'XData',F, ...
            %     'Ydata',T);

        end

        % Value changed function: StratSlider
        function StratSliderValueChanged(app, event)
            clim(app.UIAxes_Strat,app.StratSlider.Value);
        end

        % Value changed function: GroundSlider
        function GroundSliderValueChanged(app, event)
            clim(app.UIAxes_Ground,app.GroundSlider.Value);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.UIAxes_Strat = uiaxes(app.Panel);
            title(app.UIAxes_Strat, 'Stratospheric Station')
            xlabel(app.UIAxes_Strat, 'Frequency (MHz)')
            ylabel(app.UIAxes_Strat, 'Time (s)')
            zlabel(app.UIAxes_Strat, 'Gain (dB)')
            app.UIAxes_Ground = uiaxes(app.Panel);
            title(app.UIAxes_Ground, 'Ground Station')
            xlabel(app.UIAxes_Ground, 'Frequency (MHz)')
            ylabel(app.UIAxes_Ground, 'Time (s)')
            zlabel(app.UIAxes_Ground, 'Gain (dB)')
            app.Button = uibutton(app.Panel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = fullfile(pathToMLAPP, 'power.png');
            app.Button.Text = '';
            app.SyncPathStratLabel = uilabel(app.Panel);
            app.SyncPathStratLabel.Text = 'SyncPathStrat';
            app.SyncPath_Strat = uieditfield(app.Panel, 'text');
            app.SyncPath_Strat.Value = 'C:\Users\Pedro\Documents\Bexus\downsampled';
            app.TimerEditFieldLabel = uilabel(app.Panel);
            app.TimerEditFieldLabel.Text = 'Timer';
            app.Timer_Strat = uieditfield(app.Panel, 'numeric');
            app.Timer_Strat.Editable = 'off';
            app.SoundSwitch = uiswitch(app.Panel, 'toggle');
            app.OverlapLabel = uilabel(app.Panel);
            app.OverlapLabel.Text = 'Overlap';
            app.Overlap_Strat = uislider(app.Panel);
            app.Overlap_Strat.Limits = [0 99];
            app.Overlap_Strat.Value = 90;
            app.TimeResLabel = uilabel(app.Panel);
            app.TimeResLabel.Text = 'TimeRes';
            app.TimeRes_Strat = uislider(app.Panel);
            app.TimeRes_Strat.Limits = [0 0.2];
            app.TimeRes_Strat.Value = 0.1;
            app.SoundSwitchLabel = uilabel(app.Panel);
            app.SoundSwitchLabel.Text = 'Sound';
            app.OverlapLabel_2 = uilabel(app.Panel);
            app.OverlapLabel_2.Text = 'Leakage';
            app.Leakage_Strat = uislider(app.Panel);
            app.Leakage_Strat.Limits = [0 1];
            app.Leakage_Strat.Value = 0.85;
            app.TimerEditFieldLabel_2 = uilabel(app.Panel);
            app.TimerEditFieldLabel_2.Text = 'Timer';
            app.Timer_Ground = uieditfield(app.Panel, 'numeric');
            app.Timer_Ground.Editable = 'off';
            app.OverlapLabel_3 = uilabel(app.Panel);
            app.OverlapLabel_3.Text = 'Overlap';
            app.Overlap_Ground = uislider(app.Panel);
            app.Overlap_Ground.Limits = [0 99];
            app.Overlap_Ground.Value = 90;
            app.TimeResLabel_2 = uilabel(app.Panel);
            app.TimeResLabel_2.Text = 'TimeRes';
            app.TimeRes_Ground = uislider(app.Panel);
            app.TimeRes_Ground.Limits = [0 0.2];
            app.TimeRes_Ground.Value = 0.1;
            app.OverlapLabel_4 = uilabel(app.Panel);
            app.OverlapLabel_4.Text = 'Leakage';
            app.Leakage_Ground = uislider(app.Panel);
            app.Leakage_Ground.Limits = [0 1];
            app.Leakage_Ground.Value = 0.85;
            app.SyncPathStratLabel_2 = uilabel(app.Panel);
            app.SyncPathStratLabel_2.Text = 'SyncPathGround';
            app.SyncPath_Ground = uieditfield(app.Panel, 'text');
            app.SyncPath_Ground.Value = 'C:\Users\Pedro\Documents\Bexus\rf_data\raw';
            app.StratSlider = uislider(app.Panel, 'range');
            app.StratSlider.Limits = [-80 100];
            app.StratSlider.ValueChangedFcn = createCallbackFcn(app, @StratSliderValueChanged, true);
            app.StratSlider.Value = [-40 20];
            app.GroundSlider = uislider(app.Panel, 'range');
            app.GroundSlider.Limits = [-80 100];
            app.GroundSlider.ValueChangedFcn = createCallbackFcn(app, @GroundSliderValueChanged, true);
            app.GroundSlider.Value = [0 70];
            app.TimerEditFieldLabel_3 = uilabel(app.Panel);
            app.TimerEditFieldLabel_3.Text = 'Timer';
            app.Timer_DataUpdate = uieditfield(app.Panel, 'numeric');
            app.Timer_DataUpdate.Editable = 'off';
            app.DecimationFactorDropDownLabel = uilabel(app.Panel);
            app.DecimationFactorDropDownLabel.Text = {'Decimation'; 'Factor'};
            app.DecimationFactor = uidropdown(app.Panel);
            app.DecimationFactor.Items = {'16', '32', '64', '128', '1024', '2048'};
            app.DecimationFactor.Value = '32';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GroundStationApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startup)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end